classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        NewMenu                        matlab.ui.container.Menu
        OpenMenu                       matlab.ui.container.Menu
        SaveMenu                       matlab.ui.container.Menu
        ExportasPDFMenu                matlab.ui.container.Menu
        EditMenu                       matlab.ui.container.Menu
        CutMenu                        matlab.ui.container.Menu
        CopyMenu                       matlab.ui.container.Menu
        PasteMenu                      matlab.ui.container.Menu
        RunMenu                        matlab.ui.container.Menu
        RunOptimizerMenu               matlab.ui.container.Menu
        RunMotionPlannerMenu           matlab.ui.container.Menu
        TestsMenu                      matlab.ui.container.Menu
        TestCase1Menu                  matlab.ui.container.Menu
        TestCase2Menu                  matlab.ui.container.Menu
        TestCase3Menu                  matlab.ui.container.Menu
        Toolbar                        matlab.ui.container.Toolbar
        NewDocPushTool                 matlab.ui.container.toolbar.PushTool
        SaveDocPushTool                matlab.ui.container.toolbar.PushTool
        OpenDocPushTool                matlab.ui.container.toolbar.PushTool
        SelectToggleTool               matlab.ui.container.toolbar.ToggleTool
        TargetToggleTool               matlab.ui.container.toolbar.ToggleTool
        ObstacleToggleTool             matlab.ui.container.toolbar.ToggleTool
        EraserToggleTool               matlab.ui.container.toolbar.ToggleTool
        RunOptPushTool                 matlab.ui.container.toolbar.PushTool
        PausePushTool                  matlab.ui.container.toolbar.PushTool
        ContinuePushTool               matlab.ui.container.toolbar.PushTool
        StopPushTool                   matlab.ui.container.toolbar.PushTool
        CleanPushTool                  matlab.ui.container.toolbar.PushTool
        RunMotionPushTool              matlab.ui.container.toolbar.PushTool
        RestoreViewPushTool            matlab.ui.container.toolbar.PushTool
        EvoLabWebsitePushTool          matlab.ui.container.toolbar.PushTool
        TabGroup                       matlab.ui.container.TabGroup
        OptimizerTab                   matlab.ui.container.Tab
        GridLayout                     matlab.ui.container.GridLayout
        TabGroup2                      matlab.ui.container.TabGroup
        PropertiesTab                  matlab.ui.container.Tab
        GridLayout2                    matlab.ui.container.GridLayout
        YAngleEditField                matlab.ui.control.NumericEditField
        YAngleEditFieldLabel           matlab.ui.control.Label
        XAngleEditField                matlab.ui.control.NumericEditField
        XAngleEditFieldLabel           matlab.ui.control.Label
        LengthEditField                matlab.ui.control.NumericEditField
        LengthEditFieldLabel           matlab.ui.control.Label
        YcoordinateEditField           matlab.ui.control.NumericEditField
        YcoordinateEditFieldLabel      matlab.ui.control.Label
        XcoordinateEditField           matlab.ui.control.NumericEditField
        XcoordinateEditFieldLabel      matlab.ui.control.Label
        GridLayout3                    matlab.ui.container.GridLayout
        RankPartitioningAlgorithmButton  matlab.ui.control.Button
        OptimizationAlgorithmButton    matlab.ui.control.Button
        RobotParametersButton          matlab.ui.control.Button
        OptionsLabel                   matlab.ui.control.Label
        ObjectsLabel                   matlab.ui.control.Label
        Tree2                          matlab.ui.container.Tree
        Tree                           matlab.ui.container.Tree
        BaseNode                       matlab.ui.container.TreeNode
        TargetsNode                    matlab.ui.container.TreeNode
        ObstaclesNode                  matlab.ui.container.TreeNode
        OptimizerAxes                  matlab.ui.control.UIAxes
        MotionPlannerTab               matlab.ui.container.Tab
        GridLayout4                    matlab.ui.container.GridLayout
        Button                         matlab.ui.control.Button
        TabGroup3                      matlab.ui.container.TabGroup
        AnimationParametersTab         matlab.ui.container.Tab
        GridLayout5                    matlab.ui.container.GridLayout
        GrowingandretractingEditField  matlab.ui.control.NumericEditField
        GrowingandretractingEditFieldLabel  matlab.ui.control.Label
        SteeringEditField              matlab.ui.control.NumericEditField
        SteeringEditFieldLabel         matlab.ui.control.Label
        AnimationsLabel                matlab.ui.control.Label
        SpeedperFrameSlider            matlab.ui.control.Slider
        SpeedperFrameSliderLabel       matlab.ui.control.Label
        Tree4                          matlab.ui.container.Tree
        Node_2                         matlab.ui.container.TreeNode
        Node2_2                        matlab.ui.container.TreeNode
        Node3_2                        matlab.ui.container.TreeNode
        Node4_2                        matlab.ui.container.TreeNode
        Tree3                          matlab.ui.container.Tree
        Node                           matlab.ui.container.TreeNode
        Node2                          matlab.ui.container.TreeNode
        Node3                          matlab.ui.container.TreeNode
        Node4                          matlab.ui.container.TreeNode
        MotionPlannerAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        op % Description
        sp;
        gas
        XLim = [-50 200];
        ZLim =  [-50 200];    
        YLim = [-50 200]; % Description % Description
        home_base = [0 0 0 0 0];
    end
    
    methods (Access = private)


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

    axis(app.OptimizerAxes, 'equal');
    axis(app.MotionPlannerAxes,'equal');
    hold(app.OptimizerAxes,"on");
    hold(app.MotionPlannerAxes,"on");

    app.OptimizerAxes.XLim = app.XLim;
    app.OptimizerAxes.YLim = app.YLim;
    app.OptimizerAxes.ZLim = app.ZLim;
    app.MotionPlannerAxes.XLim = app.XLim;
    app.MotionPlannerAxes.YLim = app.YLim;
    app.MotionPlannerAxes.ZLim = app.ZLim;
    app.RobotParametersButton.UserData = false;

    app.OptimizerAxes.ZDir = 'Reverse';
    app.MotionPlannerAxes.ZDir = 'Reverse';
    app.OptimizerAxes.XDir = 'Reverse';
    app.MotionPlannerAxes.XDir = 'Reverse';
    plot3(app.OptimizerAxes, 0, 0, 0, 'LineWidth', 2);
    app.OptimizerAxes.ButtonDownFcn = @(src, event) onClick(app, src, event);

    % Call your function here
    plot3(app.OptimizerAxes,app.home_base(1),app.home_base(2),app.home_base(3),'--gs','LineWidth',2,'MarkerSize',10,'MarkerEdgeColor','b');

        end

        % Button down function: OptimizerAxes
        function OptimizerAxesButtonDown(app, event)
    
    % Plot the star at the clicked position (currentPos)
    plot3(app.OptimizerAxes, 10, 10, 10, '*', 'Color', 'r', 'MarkerSize', 10);
    
         
        end

        % Button pushed function: RobotParametersButton
        function RobotParametersButtonPushed(app, event)
            if ~app.RobotParametersButton.UserData
               app1;
               app.RobotParametersButton.UserData = true;
            end

            
        end

        % Callback function: TargetToggleTool
        function TargetToggleToolClicked(app, event)

            if app.TargetToggleTool.State == "off"
                app.TargetToggleTool.State = "on";
            end
            app.SelectToggleTool.State='off';
            app.ObstacleToggleTool.State = 'off';
            app.EraserToggleTool.State = 'off';

                    % Create input dialog to take X, Y, Z coordinates and angles
            prompt = {'Enter X coordinate:', 'Enter Y coordinate:', 'Enter Z coordinate:', 'Enter X Angle (degrees):', 'Enter Y Angle (degrees):'};
            dlgTitle = 'Enter Target Coordinates and Angles';
            numLines = 1;
            defaultans = {'0', '0', '0', '0', '0'};  % default values for X, Y, Z, X Angle, Y Angle
            
            % Get the coordinates and angles from the user
            answer = inputdlg(prompt, dlgTitle, numLines, defaultans);
            
            % If the user presses Cancel, answer will be empty
            if isempty(answer)
                return;
            end
            
            % Convert the input to numeric values for coordinates and angles
            x = str2double(answer{1});
            y = str2double(answer{2});
            z = str2double(answer{3});
            xAngle = str2double(answer{4});
            yAngle = str2double(answer{5});
            
            % Check if coordinates and angles are valid (numbers)
            if any(isnan([x, y, z, xAngle, yAngle]))
                uialert(app.UIFigure, 'Please enter valid numeric coordinates and angles.', 'Input Error');
                return;
            end
            
            % draw target
            plot3(app.OptimizerAxes,x, y, z,'-x','Color','b', 'LineWidth', 8);            
        
        end

        % Callback function: SelectToggleTool
        function SelectToggleToolClicked(app, event)
            if app.SelectToggleTool.State == "off"
                app.SelectToggleTool.State = "on";
            end
            app.TargetToggleTool.State='off';
            app.ObstacleToggleTool.State = 'off';
            app.EraserToggleTool.State = 'off';
        end

        % Callback function: ObstacleToggleTool
        function ObstacleToggleToolClicked(app, event)
            if app.ObstacleToggleTool.State == "off"
                app.ObstacleToggleTool.State = "on";
            end
            app.TargetToggleTool.State='off';
            app.SelectToggleTool.State = 'off';
            app.EraserToggleTool.State = 'off';


             prompt = {'Enter X coordinate:', 'Enter Y coordinate:', 'Enter Z (base) coordinate:', 'Enter radius in deg:', 'Enter height from base:'};
            dlgTitle = 'Enter Target Coordinates and Angles';
            numLines = 1;
            defaultans = {'0', '0', '0', '0', '0'};  % default values for X, Y, Z, X Angle, Y Angle
            
            % Get the coordinates and angles from the user
            answer = inputdlg(prompt, dlgTitle, numLines, defaultans);
            
            % If the user presses Cancel, answer will be empty
            if isempty(answer)
                return;
            end
            
            % Convert the input to numeric values for coordinates and angles
            x = str2double(answer{1});
            y = str2double(answer{2});
            z = str2double(answer{3});
            radius = str2double(answer{4});
            height = str2double(answer{5});
            
            % Check if coordinates and angles are valid (numbers)
            if any(isnan([x, y, z, radius, height]))
                uialert(app.UIFigure, 'Please enter valid numeric coordinates and angles.', 'Input Error');
                return;
            end
            
           %draw obstacle
            [X,Y,Z] = cylinder(radius);
            X = X + x;
            Y = Y + y;
            Z = Z*-height + z;
            plot3(app.OptimizerAxes,X,Y,Z,'Color','k');
            th = 0:pi/50:2*pi;
            xunit = radius * cos(th) + x;
            yunit = radius * sin(th) + y;
            zunit = 0*th + z;
            plot3(app.OptimizerAxes,xunit, yunit, zunit,'Color','k');
            plot3(app.OptimizerAxes,xunit, yunit, (zunit-height),'Color','k');


        end

        % Callback function: EraserToggleTool
        function EraserToggleToolClicked(app, event)
            if app.EraserToggleTool.State == "off"
                app.EraserToggleTool.State = "on";
            end
            app.TargetToggleTool.State='off';
            app.ObstacleToggleTool.State = 'off';
            app.SelectToggleTool.State = 'off';
            cursorImage = imread('icons\eraser.png'); % Replace with your own image

            % Set the cursor to custom
            set(app.OptimizerAxes, 'Pointer', 'custom', 'PointerShapeCData', cursorImage);
        end

        % Callback function: EvoLabWebsitePushTool
        function EvoLabWebsitePushToolClicked(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.NewMenu = uimenu(app.FileMenu);
            app.NewMenu.Accelerator = 'n';
            app.NewMenu.Text = 'New';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.Accelerator = 'o';
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.Accelerator = 's';
            app.SaveMenu.Text = 'Save';
            app.ExportasPDFMenu = uimenu(app.FileMenu);
            app.ExportasPDFMenu.Accelerator = 'p';
            app.ExportasPDFMenu.Text = 'Export as PDF';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Text = 'Edit';
            app.CutMenu = uimenu(app.EditMenu);
            app.CutMenu.Accelerator = 'x';
            app.CutMenu.Text = 'Cut';
            app.CopyMenu = uimenu(app.EditMenu);
            app.CopyMenu.Accelerator = 'c';
            app.CopyMenu.Text = 'Copy';
            app.PasteMenu = uimenu(app.EditMenu);
            app.PasteMenu.Accelerator = 'v';
            app.PasteMenu.Text = 'Paste';
            app.RunMenu = uimenu(app.UIFigure);
            app.RunMenu.Text = 'Run';
            app.RunOptimizerMenu = uimenu(app.RunMenu);
            app.RunOptimizerMenu.Accelerator = 'r';
            app.RunOptimizerMenu.Text = 'Run Optimizer';
            app.RunMotionPlannerMenu = uimenu(app.RunMenu);
            app.RunMotionPlannerMenu.Accelerator = 't';
            app.RunMotionPlannerMenu.Text = 'Run Motion Planner';
            app.TestsMenu = uimenu(app.UIFigure);
            app.TestsMenu.Text = 'Tests';
            app.TestCase1Menu = uimenu(app.TestsMenu);
            app.TestCase1Menu.Text = 'Test Case 1';
            app.TestCase2Menu = uimenu(app.TestsMenu);
            app.TestCase2Menu.Text = 'Test Case 2';
            app.TestCase3Menu = uimenu(app.TestsMenu);
            app.TestCase3Menu.Text = 'Test Case 3';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.NewDocPushTool = uipushtool(app.Toolbar);
            app.NewDocPushTool.Tooltip = {'New Document'};
            app.NewDocPushTool.Icon = fullfile(pathToMLAPP, 'icons', 'add-document.png');
            app.SaveDocPushTool = uipushtool(app.Toolbar);
            app.SaveDocPushTool.Tooltip = {'Save Document'};
            app.SaveDocPushTool.Icon = fullfile(pathToMLAPP, 'icons', 'disk.png');
            app.OpenDocPushTool = uipushtool(app.Toolbar);
            app.OpenDocPushTool.Tooltip = {'Open Document'};
            app.OpenDocPushTool.Icon = fullfile(pathToMLAPP, 'icons', 'folder-open.png');
            app.SelectToggleTool = uitoggletool(app.Toolbar);
            app.SelectToggleTool.Tooltip = {'Select'};
            app.SelectToggleTool.ClickedCallback = createCallbackFcn(app, @SelectToggleToolClicked, true);
            app.SelectToggleTool.Icon = fullfile(pathToMLAPP, 'icons', 'cursor.png');
            app.SelectToggleTool.Separator = 'on';
            app.SelectToggleTool.State = 'on';
            app.TargetToggleTool = uitoggletool(app.Toolbar);
            app.TargetToggleTool.Tooltip = {'Target'};
            app.TargetToggleTool.ClickedCallback = createCallbackFcn(app, @TargetToggleToolClicked, true);
            app.TargetToggleTool.Icon = fullfile(pathToMLAPP, 'icons', 'star.png');
            app.ObstacleToggleTool = uitoggletool(app.Toolbar);
            app.ObstacleToggleTool.Tooltip = {'Obstacle'};
            app.ObstacleToggleTool.ClickedCallback = createCallbackFcn(app, @ObstacleToggleToolClicked, true);
            app.ObstacleToggleTool.Icon = fullfile(pathToMLAPP, 'icons', 'circle.png');
            app.EraserToggleTool = uitoggletool(app.Toolbar);
            app.EraserToggleTool.Tooltip = {'Eraser'};
            app.EraserToggleTool.ClickedCallback = createCallbackFcn(app, @EraserToggleToolClicked, true);
            app.EraserToggleTool.Icon = fullfile(pathToMLAPP, 'icons', 'eraser.png');
            app.RunOptPushTool = uipushtool(app.Toolbar);
            app.RunOptPushTool.Tooltip = {'Run Optimizer'};
            app.RunOptPushTool.Icon = fullfile(pathToMLAPP, 'icons', 'play.png');
            app.RunOptPushTool.Separator = 'on';
            app.PausePushTool = uipushtool(app.Toolbar);
            app.PausePushTool.Tooltip = {'Pause'};
            app.PausePushTool.Icon = fullfile(pathToMLAPP, 'icons', 'pause.svg');
            app.ContinuePushTool = uipushtool(app.Toolbar);
            app.ContinuePushTool.Tooltip = {'Continue'};
            app.ContinuePushTool.Icon = fullfile(pathToMLAPP, 'icons', 'continue.svg');
            app.StopPushTool = uipushtool(app.Toolbar);
            app.StopPushTool.Tooltip = {'Stop'};
            app.StopPushTool.Icon = fullfile(pathToMLAPP, 'icons', 'stop.png');
            app.CleanPushTool = uipushtool(app.Toolbar);
            app.CleanPushTool.Tooltip = {'Clean'};
            app.CleanPushTool.Icon = fullfile(pathToMLAPP, 'icons', 'broom.png');
            app.RunMotionPushTool = uipushtool(app.Toolbar);
            app.RunMotionPushTool.Tooltip = {'Run Motion Planner'};
            app.RunMotionPushTool.Icon = fullfile(pathToMLAPP, 'icons', 'animated-icon.png');
            app.RestoreViewPushTool = uipushtool(app.Toolbar);
            app.RestoreViewPushTool.Tooltip = {'Restore View'};
            app.RestoreViewPushTool.Icon = fullfile(pathToMLAPP, 'icons', 'house-chimney.png');
            app.RestoreViewPushTool.Separator = 'on';
            app.EvoLabWebsitePushTool = uipushtool(app.Toolbar);
            app.EvoLabWebsitePushTool.Tooltip = {'EvoLab Website'};
            app.EvoLabWebsitePushTool.ClickedCallback = createCallbackFcn(app, @EvoLabWebsitePushToolClicked, true);
            app.EvoLabWebsitePushTool.Icon = fullfile(pathToMLAPP, 'icons', 'globe.png');
            app.EvoLabWebsitePushTool.Separator = 'on';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.OptimizerTab = uitab(app.TabGroup);
            app.OptimizerTab.Title = 'Optimizer';
            app.GridLayout = uigridlayout(app.OptimizerTab);
            app.OptimizerAxes = uiaxes(app.GridLayout);
            xlabel(app.OptimizerAxes, 'X')
            ylabel(app.OptimizerAxes, 'Y')
            zlabel(app.OptimizerAxes, 'Z')
            app.OptimizerAxes.ZDir = 'reverse';
            app.OptimizerAxes.ButtonDownFcn = createCallbackFcn(app, @OptimizerAxesButtonDown, true);
            app.Tree = uitree(app.GridLayout);
            app.BaseNode = uitreenode(app.Tree);
            app.BaseNode.Icon = fullfile(pathToMLAPP, 'icons', 'house-chimney.png');
            app.BaseNode.Text = 'Base';
            app.TargetsNode = uitreenode(app.Tree);
            app.TargetsNode.Icon = fullfile(pathToMLAPP, 'icons', 'star.png');
            app.TargetsNode.Text = 'Targets';
            app.ObstaclesNode = uitreenode(app.Tree);
            app.ObstaclesNode.Icon = fullfile(pathToMLAPP, 'icons', 'circle.png');
            app.ObstaclesNode.Text = 'Obstacles';
            app.Tree2 = uitree(app.GridLayout);
            app.ObjectsLabel = uilabel(app.GridLayout);
            app.ObjectsLabel.Text = 'Objects';
            app.OptionsLabel = uilabel(app.GridLayout);
            app.OptionsLabel.Text = 'Options';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.RobotParametersButton = uibutton(app.GridLayout3, 'push');
            app.RobotParametersButton.ButtonPushedFcn = createCallbackFcn(app, @RobotParametersButtonPushed, true);
            app.RobotParametersButton.Text = 'Robot Parameters';
            app.OptimizationAlgorithmButton = uibutton(app.GridLayout3, 'push');
            app.OptimizationAlgorithmButton.Text = 'Optimization Algorithm';
            app.RankPartitioningAlgorithmButton = uibutton(app.GridLayout3, 'push');
            app.RankPartitioningAlgorithmButton.Text = 'Rank Partitioning Algorithm';
            app.TabGroup2 = uitabgroup(app.GridLayout);
            app.PropertiesTab = uitab(app.TabGroup2);
            app.PropertiesTab.Title = 'Properties';
            app.GridLayout2 = uigridlayout(app.PropertiesTab);
            app.XcoordinateEditFieldLabel = uilabel(app.GridLayout2);
            app.XcoordinateEditFieldLabel.Text = 'X-coordinate';
            app.XcoordinateEditField = uieditfield(app.GridLayout2, 'numeric');
            app.YcoordinateEditFieldLabel = uilabel(app.GridLayout2);
            app.YcoordinateEditFieldLabel.Text = 'Y-coordinate';
            app.YcoordinateEditField = uieditfield(app.GridLayout2, 'numeric');
            app.LengthEditFieldLabel = uilabel(app.GridLayout2);
            app.LengthEditFieldLabel.Text = 'Length';
            app.LengthEditField = uieditfield(app.GridLayout2, 'numeric');
            app.XAngleEditFieldLabel = uilabel(app.GridLayout2);
            app.XAngleEditFieldLabel.Text = 'X-Angle';
            app.XAngleEditField = uieditfield(app.GridLayout2, 'numeric');
            app.YAngleEditFieldLabel = uilabel(app.GridLayout2);
            app.YAngleEditFieldLabel.Text = 'Y-Angle';
            app.YAngleEditField = uieditfield(app.GridLayout2, 'numeric');
            app.MotionPlannerTab = uitab(app.TabGroup);
            app.MotionPlannerTab.Title = 'Motion Planner';
            app.GridLayout4 = uigridlayout(app.MotionPlannerTab);
            app.MotionPlannerAxes = uiaxes(app.GridLayout4);
            xlabel(app.MotionPlannerAxes, 'X')
            ylabel(app.MotionPlannerAxes, 'Y')
            zlabel(app.MotionPlannerAxes, 'Z')
            app.Tree3 = uitree(app.GridLayout4);
            app.Node = uitreenode(app.Tree3);
            app.Node.Text = 'Node';
            app.Node2 = uitreenode(app.Node);
            app.Node2.Text = 'Node2';
            app.Node3 = uitreenode(app.Node);
            app.Node3.Text = 'Node3';
            app.Node4 = uitreenode(app.Node);
            app.Node4.Text = 'Node4';
            app.Tree4 = uitree(app.GridLayout4);
            app.Node_2 = uitreenode(app.Tree4);
            app.Node_2.Text = 'Node';
            app.Node2_2 = uitreenode(app.Node_2);
            app.Node2_2.Text = 'Node2';
            app.Node3_2 = uitreenode(app.Node_2);
            app.Node3_2.Text = 'Node3';
            app.Node4_2 = uitreenode(app.Node_2);
            app.Node4_2.Text = 'Node4';
            app.SpeedperFrameSliderLabel = uilabel(app.GridLayout4);
            app.SpeedperFrameSliderLabel.Text = 'Speed per Frame';
            app.SpeedperFrameSlider = uislider(app.GridLayout4);
            app.SpeedperFrameSlider.MajorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
            app.SpeedperFrameSlider.Value = 95;
            app.AnimationsLabel = uilabel(app.GridLayout4);
            app.AnimationsLabel.Text = 'Animations';
            app.TabGroup3 = uitabgroup(app.GridLayout4);
            app.AnimationParametersTab = uitab(app.TabGroup3);
            app.AnimationParametersTab.Title = 'Animation Parameters';
            app.GridLayout5 = uigridlayout(app.AnimationParametersTab);
            app.SteeringEditFieldLabel = uilabel(app.GridLayout5);
            app.SteeringEditFieldLabel.Text = 'Steering';
            app.SteeringEditField = uieditfield(app.GridLayout5, 'numeric');
            app.GrowingandretractingEditFieldLabel = uilabel(app.GridLayout5);
            app.GrowingandretractingEditFieldLabel.Text = 'Growing and retracting';
            app.GrowingandretractingEditField = uieditfield(app.GridLayout5, 'numeric');
            app.Button = uibutton(app.GridLayout4, 'push');
            app.Button.Icon = fullfile(pathToMLAPP, 'icons', 'play.png');
            app.Button.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end